classdef PVP_IPFM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PVPIPFMUIFigure                 matlab.ui.Figure
        DatabaseTableDropDown           matlab.ui.control.DropDown
        DatabaseTableDropDownLabel      matlab.ui.control.Label
        DeletedSelectedConfigsCheckBox  matlab.ui.control.CheckBox
        MaxFrequencyEditField           matlab.ui.control.NumericEditField
        MaxFrequencyEditFieldLabel      matlab.ui.control.Label
        StatisticDropDown               matlab.ui.control.DropDown
        StatisticDropDownLabel          matlab.ui.control.Label
        ViewLevelDropDown               matlab.ui.control.DropDown
        ViewLevelDropDownLabel          matlab.ui.control.Label
        FigureViewDropDown              matlab.ui.control.DropDown
        FigureViewDropDownLabel         matlab.ui.control.Label
        ReloadProfilesButton            matlab.ui.control.Button
        GenerateFigureButton            matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        ProfileSelectDropDown           matlab.ui.control.DropDown
        ProfileSelectDropDownLabel      matlab.ui.control.Label
        INITIALIZECreateDatabasesCheckBox  matlab.ui.control.CheckBox
        EnableMySQLCheckBox             matlab.ui.control.CheckBox
        SaveExcelCheckBox               matlab.ui.control.CheckBox
        ParallelizationCheckBox         matlab.ui.control.CheckBox
        SavePriorityDropDown            matlab.ui.control.DropDown
        SavePriorityDropDownLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            clc;
            [~,profile_names] = saved_profiles();
            fprintf("___________________________________________________\n")
            fprintf("|__________________________________________________|\n")
            fprintf("||                                                ||\n")
            fprintf("|| Saved Profiles:                                ||\n")
            fprintf("||________________________________________________||\n")
            for i = 1:length(profile_names)
                fprintf("||                                                ||\n")
                row = sprintf("|| %d:",i);
                fprintf("%s",row)
                for j = 1:(50-length(char(row)))
                    fprintf(" ")
                end
                fprintf("||\n")
                row = sprintf("|| %s",profile_names{i});
                fprintf("%s",row)
                for j = 1:(50-length(char(row)))
                    fprintf(" ")
                end
                fprintf("||\n")
            end
            fprintf("||________________________________________________||\n")
            fprintf("||________________________________________________||\n")
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

            app_settings.table_name = app.DatabaseTableDropDown.Value;
            app_settings.delete_sel = app.DeletedSelectedConfigsCheckBox.Value;
            app_settings.use_parellelization = app.ParallelizationCheckBox.Value;
            app_settings.priority = app.SavePriorityDropDown.Value;
            app_settings.save_mysql = app.EnableMySQLCheckBox.Value;
            app_settings.save_excel = app.SaveExcelCheckBox.Value;
            app_settings.create_database_tables = app.INITIALIZECreateDatabasesCheckBox.Value;
            app_settings.profile_sel = str2double(app.ProfileSelectDropDown.Value);
            app_settings.num_frames = app.NumberofIterationsEditField.Value;
            app_settings.delete_sel = false;
            app_settings.data_view = app.FigureViewDropDown.Value;
            app_settings.level_view = app.ViewLevelDropDown.Value;
            app_settings.data_type = app.StatisticDropDown.Value;
            app_settings.max_freq = app.MaxFrequencyEditField.Value;

            sim_head(app_settings)
        end

        % Value changed function: NumberofIterationsEditField
        function NumberofIterationsEditFieldValueChanged(app, event)

        end

        % Button pushed function: GenerateFigureButton
        function GenerateFigureButtonPushed(app, event)
            
            app_settings.table_name = app.DatabaseTableDropDown.Value;
            app_settings.delete_sel = app.DeletedSelectedConfigsCheckBox.Value;
            app_settings.use_parellelization = false;
            app_settings.priority = app.SavePriorityDropDown.Value;
            app_settings.save_mysql = app.EnableMySQLCheckBox.Value;
            app_settings.save_excel = app.SaveExcelCheckBox.Value;
            app_settings.create_database_tables = app.INITIALIZECreateDatabasesCheckBox.Value;
            app_settings.profile_sel = str2double(app.ProfileSelectDropDown.Value);
            app_settings.num_frames = 0;
            app_settings.data_view = app.FigureViewDropDown.Value;
            app_settings.level_view = app.ViewLevelDropDown.Value;
            app_settings.data_type = app.StatisticDropDown.Value;
            app_settings.max_freq = app.MaxFrequencyEditField.Value;

            sim_head(app_settings)

        end

        % Button pushed function: ReloadProfilesButton
        function ReloadProfilesButtonPushed(app, event)
            
            clc;
            [~,profile_names] = saved_profiles();
            fprintf("___________________________________________________\n")
            fprintf("|__________________________________________________|\n")
            fprintf("||                                                ||\n")
            fprintf("|| Saved Profiles:                                ||\n")
            fprintf("||________________________________________________||\n")
            for i = 1:length(profile_names)
                fprintf("||                                                ||\n")
                row = sprintf("|| %d:",i);
                fprintf("%s",row)
                for j = 1:(50-length(char(row)))
                    fprintf(" ")
                end
                fprintf("||\n")
                row = sprintf("|| %s",profile_names{i});
                fprintf("%s",row)
                for j = 1:(50-length(char(row)))
                    fprintf(" ")
                end
                fprintf("||\n")
            end
            fprintf("||________________________________________________||\n")
            fprintf("||________________________________________________||\n")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PVPIPFMUIFigure = uifigure('Visible', 'off');
            app.PVPIPFMUIFigure.Name = 'PVP-IPFM';
            app.SavePriorityDropDownLabel = uilabel(app.PVPIPFMUIFigure);
            app.SavePriorityDropDownLabel.Text = 'Save Priority';
            app.SavePriorityDropDown = uidropdown(app.PVPIPFMUIFigure);
            app.SavePriorityDropDown.Items = {'mysql', 'local'};
            app.SavePriorityDropDown.Value = 'mysql';
            app.ParallelizationCheckBox = uicheckbox(app.PVPIPFMUIFigure);
            app.ParallelizationCheckBox.Text = 'Parallelization';
            app.ParallelizationCheckBox.Value = true;
            app.SaveExcelCheckBox = uicheckbox(app.PVPIPFMUIFigure);
            app.SaveExcelCheckBox.Text = 'Save Excel';
            app.EnableMySQLCheckBox = uicheckbox(app.PVPIPFMUIFigure);
            app.EnableMySQLCheckBox.Text = 'Enable MySQL';
            app.EnableMySQLCheckBox.Value = true;
            app.INITIALIZECreateDatabasesCheckBox = uicheckbox(app.PVPIPFMUIFigure);
            app.INITIALIZECreateDatabasesCheckBox.Text = 'INITIALIZE - Create Databases';
            app.ProfileSelectDropDownLabel = uilabel(app.PVPIPFMUIFigure);
            app.ProfileSelectDropDownLabel.Text = 'Profile Select';
            app.ProfileSelectDropDown = uidropdown(app.PVPIPFMUIFigure);
            app.ProfileSelectDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ProfileSelectDropDown.Value = '1';
            app.NumberofIterationsEditFieldLabel = uilabel(app.PVPIPFMUIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.PVPIPFMUIFigure, 'numeric');
            app.NumberofIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofIterationsEditFieldValueChanged, true);
            app.NumberofIterationsEditField.Value = 1;
            app.SimulateButton = uibutton(app.PVPIPFMUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.GenerateFigureButton = uibutton(app.PVPIPFMUIFigure, 'push');
            app.GenerateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFigureButtonPushed, true);
            app.GenerateFigureButton.Text = 'Generate Figure';
            app.ReloadProfilesButton = uibutton(app.PVPIPFMUIFigure, 'push');
            app.ReloadProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadProfilesButtonPushed, true);
            app.ReloadProfilesButton.Text = 'Reload Profiles';
            app.FigureViewDropDownLabel = uilabel(app.PVPIPFMUIFigure);
            app.FigureViewDropDownLabel.Text = 'Figure View';
            app.FigureViewDropDown = uidropdown(app.PVPIPFMUIFigure);
            app.FigureViewDropDown.Items = {'figure', 'roc', 'table'};
            app.FigureViewDropDown.Value = 'figure';
            app.ViewLevelDropDownLabel = uilabel(app.PVPIPFMUIFigure);
            app.ViewLevelDropDownLabel.Text = 'View Level';
            app.ViewLevelDropDown = uidropdown(app.PVPIPFMUIFigure);
            app.ViewLevelDropDown.Items = {'win', 'pat'};
            app.ViewLevelDropDown.Value = 'win';
            app.StatisticDropDownLabel = uilabel(app.PVPIPFMUIFigure);
            app.StatisticDropDownLabel.Text = 'Statistic';
            app.StatisticDropDown = uidropdown(app.PVPIPFMUIFigure);
            app.StatisticDropDown.Items = {'accy', 'spec', 'sens'};
            app.StatisticDropDown.Value = 'accy';
            app.MaxFrequencyEditFieldLabel = uilabel(app.PVPIPFMUIFigure);
            app.MaxFrequencyEditFieldLabel.Text = 'Max Frequency';
            app.MaxFrequencyEditField = uieditfield(app.PVPIPFMUIFigure, 'numeric');
            app.MaxFrequencyEditField.Value = 30;
            app.DeletedSelectedConfigsCheckBox = uicheckbox(app.PVPIPFMUIFigure);
            app.DeletedSelectedConfigsCheckBox.Text = {'Deleted'; 'Selected'; 'Configs'};
            app.DatabaseTableDropDownLabel = uilabel(app.PVPIPFMUIFigure);
            app.DatabaseTableDropDownLabel.Text = 'Database Table';
            app.DatabaseTableDropDown = uidropdown(app.PVPIPFMUIFigure);
            app.DatabaseTableDropDown.Items = {'lrm_results_v2', 'bleeding_pigs_v1'};
            app.DatabaseTableDropDown.Value = 'lrm_results_v2';
            % Show the figure after all components are created
            app.PVPIPFMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PVP_IPFM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PVPIPFMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PVPIPFMUIFigure)
        end
    end
end